classdef Load_Pills_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoadMedsUIFigure              matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        CassetteListBox               matlab.ui.control.ListBox
        CassetteListBoxLabel          matlab.ui.control.Label
        ChamberListBox                matlab.ui.control.ListBox
        ChamberListBoxLabel           matlab.ui.control.Label
        DosageButtonGroup             matlab.ui.container.ButtonGroup
        NumberofpillstobetakenLabel   matlab.ui.control.Label
        OneButton                     matlab.ui.control.RadioButton
        TwoButton                     matlab.ui.control.RadioButton
        ThreeButton                   matlab.ui.control.RadioButton
        UITable                       matlab.ui.control.Table
        SaveButton                    matlab.ui.control.Button
        ClearButton                   matlab.ui.control.Button
        FinishedButton                matlab.ui.control.Button
        MedicationNameEditField       matlab.ui.control.EditField
        MedicationNameEditFieldLabel  matlab.ui.control.Label
        OccurrenceButtonGroup         matlab.ui.container.ButtonGroup
        TimeofdaytobetakenSelectallthatapplyLabel  matlab.ui.control.Label
        MorningCheckBox               matlab.ui.control.CheckBox
        AfternoonCheckBox             matlab.ui.control.CheckBox
        EveningCheckBox               matlab.ui.control.CheckBox
        NightCheckBox                 matlab.ui.control.CheckBox
        TextArea                      matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        CallingApp % Main App Object
    end
    
    methods (Access = private)
        
        function RowNum = GetRowNum(app)
            % Determine which row of the table to assign information by
            % using the value of the chamber selected and the cassette
            ChamberValue = str2double(app.ChamberListBox.Value(8:end));
            if strcmp(app.CassetteListBox.Value, 'Cassette A') == 1
                Row = ChamberValue+1;
            elseif strcmp(app.CassetteListBox.Value, 'Cassette B') == 1
                Row = ChamberValue+11;
            else
                Row = ChamberValue+21;
            end
            
            % Make a cell reference from the row number to pass to the
            % writetable function
            RowNum = sprintf("A%d", Row);
        end
        
        function  UpdateUITable(app)
            % Update UI Table with medication info
            % Specify the options for the spreadsheet import to control
            % what the table uses
            numVars = 8;
            varNames = {'Cassette', 'Chamber', 'Medication', 'Morning', 'Afternoon', 'Evening', 'Night', 'Dosage'};
            varTypes = {'char','char','char','char','char','char','char','double'};
            dataStartLoc = 'A2';
            opts = spreadsheetImportOptions('NumVariables',numVars,...
                                            'VariableNames',varNames,...
                                            'VariableTypes', varTypes,...
                                            'DataRange',dataStartLoc);
            % These are used to get file directory information
            pathname = mfilename('fullpath'); % Get the current fullpath
            [filepath,~,~] = fileparts(pathname); % Get the filepath
            medfile = fullfile(filepath,'MedicationRegime.xls'); % Create the name
            % Read the excel file containing the medication information
            tabdata = readtable(medfile,opts);
            
            % Merge the timing variables into one for cleaner display in
            % the UI
            tabdata = mergevars(tabdata, {'Morning', 'Afternoon', 'Evening', 'Night'},'NewVariableName', 'Occurrence');
            
            % Missing info causes the UI table to display oddly so the
            % missing cells are given placeholders however, these fail if
            % the full table is empty so needs to be a conditional
            % statement to avoid errors
            if isempty(tabdata) ~= 1
                tabdata = fillmissing(tabdata, 'constant', 0,'DataVariables',@isnumeric);
                tabdata = fillmissing(tabdata, 'constant', ' ','DataVariables',{'Occurrence'});
            end
            
            % Update the actual UI component
            app.UITable.Data = tabdata;
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, cassette, chamber)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
        
            % Update UI with input values
            app.CassetteListBox.Value = cassette;
            app.ChamberListBox.Value = chamber;
            
            % Update UI Table with medication info
            UpdateUITable(app)
        end

        % Button pushed function: FinishedButton
        function FinishedButtonPushed(app, event)
            % Call main app's public function
            updatetext(app.CallingApp, app.CassetteListBox.Value, app.ChamberListBox.Value);
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: LoadMedsUIFigure
        function LoadMedsUIFigureCloseRequest(app, event)
           % Enable the Load Pills button in main app
           app.CallingApp.LoadMedsButton.Enable = 'on';
                    
           % Delete the dialog box 
           delete(app)
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Message to show that the information has been updated
            RecStoreMessage = "Information for %s, %s has been stored! %s";
            app.TextArea.Value = sprintf(RecStoreMessage, app.CassetteListBox.Value, app.ChamberListBox.Value);
            
            % Determine which row of the table to assign the information
            % using the rownum function
            RowNum = GetRowNum(app);
            
            % Occurrence Array
%             Occurrence = [app.MorningCheckBox.Value app.AfternoonCheckBox.Value...
%                 app.EveningCheckBox.Value app.NightCheckBox.Value]+0; % The "+0" stops the array being logical
            
            % Checking to see which times have been selected for
            % medications and giving them a value for the table
            if app.MorningCheckBox.Value == 1
                Morn = "Morning";
            else 
                Morn = char.empty;
            end
            
            if app.AfternoonCheckBox.Value == 1
                Aft = "Afternoon";
            else 
                Aft = char.empty;
            end
            
            if app.EveningCheckBox.Value == 1
                Eve = "Evening";
            else 
                Eve = char.empty;
            end
            
            if app.NightCheckBox.Value == 1
                Night = "Night";
            else 
                Night = char.empty;
            end
            
            % Determine which dosage is to be given and assigned a
            % numerical value
            if app.OneButton.Value == 1
                Dosage = 1;
            elseif app.TwoButton.Value == 1
                Dosage = 2;
            else
                Dosage = 3;
            end
                       
            % Update the medication file with the new information based on
            % the values in the UI
            MedInfo = {app.CassetteListBox.Value app.ChamberListBox.Value...
                app.MedicationNameEditField.Value Morn Aft Eve Night Dosage};
            % The cell of this information needs converted to a table
            % format
            MedTable = cell2table(MedInfo);
            
            % These are used to get file directory information
            pathname = mfilename('fullpath'); % Get the current fullpath
            [filepath,~,~] = fileparts(pathname); % Get the filepath
            medfile = fullfile(filepath,'MedicationRegime.xls'); % Create the name
            
            % Write the info to the file
            writetable(MedTable,medfile,"Range",RowNum,"WriteVariableNames",false);
            
            % Update UI Table with new medication info
            UpdateUITable(app)
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            % Message to show that the information has been updated
            RecClearMessage = "Information for %s %s has been cleared! %s";
            app.TextArea.Value = sprintf(RecClearMessage, app.CassetteListBox.Value, app.ChamberListBox.Value);
            
            % Determine which row of the table to clear the information
            % from
            RowNum = GetRowNum(app);
            
            % Create blank information to overwrite the information with
            MedClearInfo = {'' '' '' '' '' '' '' '' };
            % Convert cell to a table
            MedClearTable = cell2table(MedClearInfo);
            
            % These are used to get file directory information
            pathname = mfilename('fullpath'); % Get the current fullpath
            [filepath,~,~] = fileparts(pathname); % Get the filepath
            medfile = fullfile(filepath,'MedicationRegime.xls'); % Create the name
            
            % Overwrite the old information
            writetable(MedClearTable,medfile,"Range",RowNum,"WriteVariableNames",false);
            
            % Update UI Table with new medication info
            UpdateUITable(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoadMedsUIFigure = uifigure('Visible', 'off');
            app.LoadMedsUIFigure.Name = 'Load Meds';
            app.LoadMedsUIFigure.CloseRequestFcn = createCallbackFcn(app, @LoadMedsUIFigureCloseRequest, true);
            app.Panel = uipanel(app.LoadMedsUIFigure);
            app.CassetteListBox = uilistbox(app.Panel);
            app.CassetteListBox.Items = {'Cassette A', 'Cassette B', 'Cassette C'};
            app.CassetteListBox.Value = 'Cassette A';
            app.CassetteListBoxLabel = uilabel(app.Panel);
            app.CassetteListBoxLabel.Text = 'Cassette';
            app.ChamberListBox = uilistbox(app.Panel);
            app.ChamberListBox.Items = {'Chamber 1', 'Chamber 2', 'Chamber 3', 'Chamber 4', 'Chamber 5', 'Chamber 6', 'Chamber 7', 'Chamber 8', 'Chamber 9', 'Chamber 10'};
            app.ChamberListBox.Value = 'Chamber 1';
            app.ChamberListBoxLabel = uilabel(app.Panel);
            app.ChamberListBoxLabel.Text = 'Chamber';
            app.DosageButtonGroup = uibuttongroup(app.Panel);
            app.DosageButtonGroup.Title = 'Dosage';
            app.NumberofpillstobetakenLabel = uilabel(app.DosageButtonGroup);
            app.NumberofpillstobetakenLabel.Text = 'Number of pills to be taken';
            app.OneButton = uiradiobutton(app.DosageButtonGroup);
            app.OneButton.Text = 'One';
            app.OneButton.Value = true;
            app.TwoButton = uiradiobutton(app.DosageButtonGroup);
            app.TwoButton.Text = 'Two';
            app.ThreeButton = uiradiobutton(app.DosageButtonGroup);
            app.ThreeButton.Text = 'Three';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Cassette'; 'Chamber'; 'Medication'; 'Occurrence'; 'Dosage'};
            app.UITable.RowName = {};
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ClearButton = uibutton(app.Panel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = {'Clear'; ''};
            app.FinishedButton = uibutton(app.Panel, 'push');
            app.FinishedButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedButtonPushed, true);
            app.FinishedButton.Text = 'Finished';
            app.MedicationNameEditField = uieditfield(app.Panel, 'text');
            app.MedicationNameEditFieldLabel = uilabel(app.Panel);
            app.MedicationNameEditFieldLabel.Text = 'Medication Name';
            app.OccurrenceButtonGroup = uibuttongroup(app.Panel);
            app.OccurrenceButtonGroup.Title = 'Occurrence';
            app.TimeofdaytobetakenSelectallthatapplyLabel = uilabel(app.OccurrenceButtonGroup);
            app.TimeofdaytobetakenSelectallthatapplyLabel.Text = 'Time of day to be taken (Select all that apply)';
            app.MorningCheckBox = uicheckbox(app.OccurrenceButtonGroup);
            app.MorningCheckBox.Text = 'Morning';
            app.AfternoonCheckBox = uicheckbox(app.OccurrenceButtonGroup);
            app.AfternoonCheckBox.Text = 'Afternoon';
            app.EveningCheckBox = uicheckbox(app.OccurrenceButtonGroup);
            app.EveningCheckBox.Text = 'Evening';
            app.NightCheckBox = uicheckbox(app.OccurrenceButtonGroup);
            app.NightCheckBox.Text = 'Night';
            app.TextArea = uitextarea(app.Panel);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.LoadMedsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Load_Pills_Final(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoadMedsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoadMedsUIFigure)
        end
    end
end