classdef Main_Dispenser_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainDispenserUIFigure          matlab.ui.Figure
        Lamp_10                        matlab.ui.control.Lamp
        Label_10                       matlab.ui.control.Label
        Lamp_9                         matlab.ui.control.Lamp
        Label_9                        matlab.ui.control.Label
        Lamp_5                         matlab.ui.control.Lamp
        Label_5                        matlab.ui.control.Label
        Lamp_7                         matlab.ui.control.Lamp
        Label_7                        matlab.ui.control.Label
        Lamp_6                         matlab.ui.control.Lamp
        Label_6                        matlab.ui.control.Label
        Lamp_8                         matlab.ui.control.Lamp
        Label_8                        matlab.ui.control.Label
        Lamp_4                         matlab.ui.control.Lamp
        Label_4                        matlab.ui.control.Label
        Lamp_3                         matlab.ui.control.Lamp
        Label_3                        matlab.ui.control.Label
        Lamp_2                         matlab.ui.control.Lamp
        Label_2                        matlab.ui.control.Label
        Lamp_1                         matlab.ui.control.Lamp
        Label                          matlab.ui.control.Label
        DispenseMedsButton             matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        TextAreaLabel                  matlab.ui.control.Label
        MedsReadyLamp                  matlab.ui.control.Lamp
        MedsReadyLampLabel             matlab.ui.control.Label
        ChutePositionGauge             matlab.ui.control.SemicircularGauge
        ChutePositionGaugeLabel        matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        CurrentCassestteButtonGroup    matlab.ui.container.ButtonGroup
        CassetteAButton                matlab.ui.control.ToggleButton
        CassetteBButton                matlab.ui.control.ToggleButton
        CassetteCButton                matlab.ui.control.ToggleButton
        LoadMedsButton                 matlab.ui.control.Button
        DispensingScheduleButtonGroup  matlab.ui.container.ButtonGroup
        DailyButton                    matlab.ui.control.ToggleButton
        TimedButton                    matlab.ui.control.ToggleButton
        ClockTextArea                  matlab.ui.control.TextArea
        ClockTextAreaLabel             matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        UITable                        matlab.ui.control.Table
        ResetButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        DialogApp                       % Load_Pills App
        CurrentCassette = "Cassette A"  % Default Cassette Number 
        CurrentChamber = "Chamber 1"    % Default Chamber Number
        
        DigiClock           % Current Time clock object
        MedTimeClock        % Medicine clock object
        TOD                 % Time of Day (TOD) variable
        DispenseCount = 0;  % Counter to check for previous dispensing
    end
    
    methods (Access = public)
        
        % Function used to update the text area (NOT EXPLICITLY USED, part
        % of ensuring information passed between apps)
        function updatetext(app, cassette, chamber)
            % Store inputs as properties
            app.CurrentCassette = cassette;
            app.CurrentChamber = chamber;
            
            % Update Text Area with a string
            Regime = "%s, %s";
            app.TextArea.Value = sprintf(Regime, cassette, chamber);
            
            % Re-enable the Load Pills button
            app.LoadMedsButton.Enable = 'on';
        end
        
        % Function that is called each second to update the clock display.
        % This is always running in the app.
        function DigiClockFcn(app,~,~)  %results = func(app)
            CurrentTime = datestr(datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss Z'));
            TimeText = "%s";
            app.ClockTextArea.Value = sprintf(TimeText, CurrentTime);
        end
        
        % Function that is called each minute to update the pill times.
        % This is only used when medication dispensing is on the timed
        % option.
        function MedTimeFcn(app,~,~)  %results = func(app)
            
            TimeNow = datetime('now', 'Format', 'HH:mm'); % Establishes current time
            
            % This section checks the current time and if it is within
            % certain segments of the day it determines the appropriate TOD
            % to use.
            % PillTime variable is when the medication should be dispensed
            % for that period of the day
            if TimeNow >= datetime('00:00', 'Format', 'HH:mm') && TimeNow <= datetime('12:00', 'Format', 'HH:mm')
                app.TOD = {'Morning'};
                PillTime = datetime('11:00', 'Format', 'HH:mm');
            elseif TimeNow > datetime('12:00', 'Format', 'HH:mm') && TimeNow <= datetime('15:00', 'Format', 'HH:mm')
                app.TOD = {'Afternoon'};
                PillTime = datetime('13:30:00', 'Format', 'HH:mm');
            elseif TimeNow > datetime('15:00', 'Format', 'HH:mm') && TimeNow <= datetime('18:00', 'Format', 'HH:mm')
                app.TOD = {'Evening'};
                PillTime = datetime('19:00:00', 'Format', 'HH:mm');
            else
                app.TOD = {'Night'};
                PillTime = datetime('21:00:00', 'Format', 'HH:mm');
            end
            
            % This variable is the time difference between the Pill time
            % and current time. It is rounded up towards positive infinity
            % be 'ceil' to avoid counters showing zero
            TimeDiff = ceil(minutes(PillTime-TimeNow));
            
            % This section checks when to enable the dispensing button and
            % siplays the appropriate message.
            % It also forms part of a check to see if the medication has
            % already been dispensed for that TOD slot by using the
            % dispense count. This value is updated after the dispense meds
            % button has been pressed.
            if TimeDiff >= 1 && app.DispenseCount == 0
                app.MedsReadyLamp.Enable = 'off';
                app.DispenseMedsButton.Enable = 'off';
                TimeMes = '%s medications available in %.0f minutes at %s';
                app.EditField.Value = sprintf(TimeMes,char(app.TOD), TimeDiff, PillTime);
            elseif TimeDiff < 1 && app.DispenseCount == 0
                app.MedsReadyLamp.Enable = 'on';
                app.DispenseMedsButton.Enable = 'on';
                TimeMes = 'Please dispense your %s medications.';
                app.EditField.Value = sprintf(TimeMes,char(app.TOD));
            elseif app.DispenseCount == 1
                app.MedsReadyLamp.Enable = 'off';
                app.DispenseMedsButton.Enable = 'off';
                TimeMes = 'You have already dispensed your %s medications.';
                app.EditField.Value = sprintf(TimeMes,char(app.TOD));    
            end
        end
      
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Call updatetext to display an initial value
            updatetext(app, app.CurrentCassette, app.CurrentChamber)
            
            % Creating the timer that runs the digital clock, it calls the
            % 'TimerFcn' each second and this updates the text output
            STRT = 0; %60 - str2double(datestr(now,'ss')); % So we can update every minute.             
            app.DigiClock = timer('Name','DigiClock',...
                        'Period',1,...  % Update the time every 60 seconds.
                        'StartDelay',STRT,... % In seconds.
                        'TasksToExecute',inf,...  % number of times to update
                        'ExecutionMode','fixedSpacing',...
                        'TimerFcn',{@app.DigiClockFcn}); % The function that is run on each interval of the timer
            start(app.DigiClock); % Start the timer
        end

        % Button pushed function: LoadMedsButton
        function LoadMedsButtonPushed(app, event)
            % Disable Load Pills button while dialog is open
            app.LoadMedsButton.Enable = 'off';
        
            % Open the Load Pills dialog and pass inputs
            app.DialogApp = Load_Pills_Final(app, app.CurrentCassette, app.CurrentChamber);
        end

        % Selection changed function: DispensingScheduleButtonGroup
        function DispensingScheduleButtonGroupSelectionChanged(app, event)
            % This section switches what happens based on the user
            % selecting a different schedule button.
            selectedButton = app.DispensingScheduleButtonGroup.SelectedObject;
            
            % First, if the button is for Timed, it stops the dispense meds
            % button being active. This is reactivated in the MedTimeFcn
            % depending on the appropriate schedule
            if selectedButton == app.TimedButton
                app.DispenseMedsButton.Enable = 'off';
                
                % The function is called here first so that the display is
                % updated straight away instead of waiting for a minute  
                MedTimeFcn(app)
                
                % A timer is then created very similarly to the one that
                % runs for the clock
                STRT2 = 60 - str2double(datestr(now,'ss')); % So we can update every minute.             
                app.MedTimeClock = timer('Name','MedTimeClock',...
                            'Period',60,...  % Update the time every 60 seconds.
                            'StartDelay',STRT2,... % In seconds.
                            'TasksToExecute',inf,...  % number of times to update
                            'ExecutionMode','fixedSpacing',...
                            'TimerFcn',{@app.MedTimeFcn}); % The function that is run on each interval of the timer
                start(app.MedTimeClock);
                
            % If the button is instead for Daily dispensing, the dispensing
            % button is reanabled. Incase other options have been ran
            % before, the message area is cleared and the timer is stopped.
            elseif selectedButton == app.DailyButton
                app.DispenseMedsButton.Enable = 'on';
                app.EditField.Value = sprintf(' ');
                stop(app.MedTimeClock);
            end
        end

        % Button pushed function: DispenseMedsButton
        function DispenseMedsButtonPushed(app, event)
            % Specify the options for the spreadsheet import to control
            % what the main UI table uses
            numVars = 8;
            varNames = {'Cassette', 'Chamber', 'Medication', 'Morning', 'Afternoon', 'Evening', 'Night', 'Dosage'};
            varTypes = {'char','char','char','char','char','char','char','double'};
            dataStartLoc = 'A2';
            opts = spreadsheetImportOptions('NumVariables',numVars,...
                                            'VariableNames',varNames,...
                                            'VariableTypes', varTypes,...
                                            'DataRange',dataStartLoc);
            
            % These are used to get file directory information
            pathname = mfilename('fullpath'); % Get the current fullpath
            [filepath,~,~] = fileparts(pathname); % Get the filepath
            medfile = fullfile(filepath,'MedicationRegime.xls'); % Create the name
            
            % Read the excel file containing the medication information
            tabdata = readtable(medfile,opts);
            
            
            % Create arrays of the Cassette buttons values and values to
            % match this with
            CassButt = [app.CassetteAButton.Value app.CassetteBButton.Value app.CassetteCButton.Value];
            CassSel = {'Cassette A' 'Cassette B' 'Cassette C'};
            
            % CassRows becomes a logical array of which rows in the tabdata
            % Cassette column match with the currently selected cassette 
            % button on the main UI.
            % These rows create a new filtered table to be used, CassTable
            CassRows = (strcmp(tabdata.Cassette, CassSel(CassButt==1)));
            CassTable = tabdata(CassRows,:);
            
            % Time Of Day (TOD) array for the periods of the day that meds
            % have to be dispensed in. This contains all four values for
            % the daily dispensing. If timed, then the value is the single
            % one assigned in the function MedTimeFcn
            if app.DailyButton.Value == true
                app.TOD = {'Morning' 'Afternoon' 'Evening' 'Night'};
            end
                
            % The first for loop cycles through the periods of the day
            % depending on how many intervals have been specified in the
            % TOD array (Daily = 4, Timed = 1)
            for n = 1:length(app.TOD)
                
                % A message is didplayed to make it easier to see what
                % medication time is being dispensed
                mes = 'Dispensing %s medication now';
                app.EditField.Value = sprintf(mes, char(app.TOD{n}));
                
                % The gauge acts as a guide of where a dispenser chute
                % would be located and indicates the time of day being
                % filled. If daily it moves through the positions to match
                % the time.
                if app.DailyButton.Value == true
                    app.ChutePositionGauge.Value = n;
                else
                    app.ChutePositionGauge.Value = 2.5;
                end
                
                
                % CassTable is now filtered down to rows that apply to the
                % time of day by comparing the string values
                TimeRows = (strcmp((table2cell(CassTable(:,app.TOD(n)))),app.TOD(n)));
                
                % The appropriate information from CassTable is then 
                % displayed on the UI table including the appropriate headers          
                app.UITable.Data = CassTable(TimeRows,{'Chamber','Medication','Dosage'});
                
                % An array of the Lamp Components is made
                Lamps = [app.Lamp_1 app.Lamp_2 app.Lamp_3 app.Lamp_4 app.Lamp_5...
                     app.Lamp_6 app.Lamp_7 app.Lamp_8 app.Lamp_9 app.Lamp_10];
                
                % To get a corresponding lamp number to use, the chamber
                % information is taken from the table for the active rows.
                % An array of equal size is made of zeros for efficiancy
                % before being passed to the next loop
                LampNumText = CassTable.Chamber(TimeRows);
                LampNumDigit = zeros(length(LampNumText),1);
                
                % This loop goes through each row of the LampNumText and
                % strips it down to a character array of the chamber value
                % then converts it to a number before storing it in the 
                % digit array
                for i = 1:length(LampNumText)
                    Digit = char(LampNumText(i));
                    LampNumDigit(i) = str2double(Digit(8:end));
                end
                
                % An array of the dosage values is created. This is more
                % straightforward since already a double (numeric) value
                Dose = CassTable.Dosage(TimeRows);
                
                % This nested loop makes the appropriate lamp blink
                % correspondingly with the number of doses associated with
                % that particular chamber. (i.e. 2 doses in chamber five
                % would make lamp 5 switch on and off 2 times)
                for i = 1:length(Dose)
                    LampNum = LampNumDigit(i);
                    for ii = (1:Dose(i))
                        set((Lamps(LampNum)),'Enable','on');
                        pause(0.5) % To allow the blinking to be seen
                        set((Lamps(LampNum)),'Enable','off');
                        pause(0.5)
                    end
                end
                pause(2) % To create a small gap before commencing the next TOD
            end
            
            % Display a message to show daily dispensing is done and switch on
            % the lamp to say that meds are ready
            if app.DailyButton.Value == true
                app.EditField.Value = sprintf('Finished dispensing daily medications!');
                app.MedsReadyLamp.Enable = 'on';
                app.UITable.Data = '';
                app.ChutePositionGauge.Value = 2.5;
            else
                % Display a message for the TOD dispensing, Pause to allow
                % for this to be read. Update the count so another amount
                % cannot be dispensed, clear the table, and return to the
                % function checking the times.
                app.EditField.Value = sprintf('Finished dispensing %s medications!', char(app.TOD));
                pause(5);
                app.DispenseCount = 1;
                app.UITable.Data = '';
                MedTimeFcn(app)
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Simple reset button to clear table area & message field, turn
            % off med lamp, re-enable the Dispense meds button, and reset 
            % the chute position. Also resets the  dispense count and
            % returns the schedule button to daily.
            app.UITable.Data = '';
            app.EditField.Value = sprintf(' ');
            app.MedsReadyLamp.Enable = 'off';
            app.DispenseMedsButton.Enable = 'on';
            app.ChutePositionGauge.Value = 2.5;
            app.DispenseCount = 0;
            app.DispensingScheduleButtonGroup.SelectedObject = app.DailyButton;
        end

        % Close request function: MainDispenserUIFigure
        function MainDispenserUIFigureCloseRequest(app, event)
            % This section handles what happens if someone closes the app
            % Delete dialog app (Load pills app)
            delete(app.DialogApp)
            
            % Stops all timers, then deletes all timers and main app
            stop(timerfindall);
            delete(timerfindall);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainDispenserUIFigure = uifigure('Visible', 'off');
            app.MainDispenserUIFigure.Name = 'Main Dispenser';
            app.MainDispenserUIFigure.CloseRequestFcn = createCallbackFcn(app, @MainDispenserUIFigureCloseRequest, true);
            app.Lamp_10 = uilamp(app.MainDispenserUIFigure);
            app.Lamp_10.Enable = 'off';
            app.Label_10 = uilabel(app.MainDispenserUIFigure);
            app.Label_10.Text = '10';
            app.Lamp_9 = uilamp(app.MainDispenserUIFigure);
            app.Lamp_9.Enable = 'off';
            app.Label_9 = uilabel(app.MainDispenserUIFigure);
            app.Label_9.Text = '9';
            app.Lamp_5 = uilamp(app.MainDispenserUIFigure);
            app.Lamp_5.Enable = 'off';
            app.Label_5 = uilabel(app.MainDispenserUIFigure);
            app.Label_5.Text = '5';
            app.Lamp_7 = uilamp(app.MainDispenserUIFigure);
            app.Lamp_7.Enable = 'off';
            app.Label_7 = uilabel(app.MainDispenserUIFigure);
            app.Label_7.Text = '7';
            app.Lamp_6 = uilamp(app.MainDispenserUIFigure);
            app.Lamp_6.Enable = 'off';
            app.Label_6 = uilabel(app.MainDispenserUIFigure);
            app.Label_6.Text = '6';
            app.Lamp_8 = uilamp(app.MainDispenserUIFigure);
            app.Lamp_8.Enable = 'off';
            app.Label_8 = uilabel(app.MainDispenserUIFigure);
            app.Label_8.Text = '8';
            app.Lamp_4 = uilamp(app.MainDispenserUIFigure);
            app.Lamp_4.Enable = 'off';
            app.Label_4 = uilabel(app.MainDispenserUIFigure);
            app.Label_4.Text = '4';
            app.Lamp_3 = uilamp(app.MainDispenserUIFigure);
            app.Lamp_3.Enable = 'off';
            app.Label_3 = uilabel(app.MainDispenserUIFigure);
            app.Label_3.Text = '3';
            app.Lamp_2 = uilamp(app.MainDispenserUIFigure);
            app.Lamp_2.Enable = 'off';
            app.Label_2 = uilabel(app.MainDispenserUIFigure);
            app.Label_2.Text = '2';
            app.Lamp_1 = uilamp(app.MainDispenserUIFigure);
            app.Lamp_1.Enable = 'off';
            app.Label = uilabel(app.MainDispenserUIFigure);
            app.Label.Text = '1';
            app.DispenseMedsButton = uibutton(app.MainDispenserUIFigure, 'push');
            app.DispenseMedsButton.ButtonPushedFcn = createCallbackFcn(app, @DispenseMedsButtonPushed, true);
            app.DispenseMedsButton.Text = 'Dispense Meds';
            app.TextArea = uitextarea(app.MainDispenserUIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.TextAreaLabel = uilabel(app.MainDispenserUIFigure);
            app.TextAreaLabel.Visible = 'off';
            app.TextAreaLabel.Text = 'Text Area';
            app.MedsReadyLamp = uilamp(app.MainDispenserUIFigure);
            app.MedsReadyLamp.Enable = 'off';
            app.MedsReadyLampLabel = uilabel(app.MainDispenserUIFigure);
            app.MedsReadyLampLabel.Text = 'Meds Ready';
            app.ChutePositionGauge = uigauge(app.MainDispenserUIFigure, 'semicircular');
            app.ChutePositionGauge.Limits = [1 4];
            app.ChutePositionGauge.MajorTicks = [1 2 3 4];
            app.ChutePositionGauge.MajorTickLabels = {'Morn', 'Aft', 'Eve', 'Night'};
            app.ChutePositionGauge.ScaleDirection = 'counterclockwise';
            app.ChutePositionGauge.MinorTicks = [];
            app.ChutePositionGauge.Value = 2.5;
            app.ChutePositionGaugeLabel = uilabel(app.MainDispenserUIFigure);
            app.ChutePositionGaugeLabel.Text = 'Chute Position';
            app.Panel = uipanel(app.MainDispenserUIFigure);
            app.CurrentCassestteButtonGroup = uibuttongroup(app.Panel);
            app.CurrentCassestteButtonGroup.Title = 'Current Cassestte:';
            app.CassetteAButton = uitogglebutton(app.CurrentCassestteButtonGroup);
            app.CassetteAButton.Text = 'Cassette A';
            app.CassetteAButton.Value = true;
            app.CassetteBButton = uitogglebutton(app.CurrentCassestteButtonGroup);
            app.CassetteBButton.Text = 'Cassette B';
            app.CassetteCButton = uitogglebutton(app.CurrentCassestteButtonGroup);
            app.CassetteCButton.Text = 'Cassette C';
            app.LoadMedsButton = uibutton(app.Panel, 'push');
            app.LoadMedsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMedsButtonPushed, true);
            app.LoadMedsButton.Text = 'Load Meds';
            app.DispensingScheduleButtonGroup = uibuttongroup(app.Panel);
            app.DispensingScheduleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DispensingScheduleButtonGroupSelectionChanged, true);
            app.DispensingScheduleButtonGroup.Title = 'Dispensing Schedule:';
            app.DailyButton = uitogglebutton(app.DispensingScheduleButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.DailyButton.Value = true;
            app.TimedButton = uitogglebutton(app.DispensingScheduleButtonGroup);
            app.TimedButton.Text = 'Timed';
            app.ClockTextArea = uitextarea(app.Panel);
            app.ClockTextArea.Editable = 'off';
            app.ClockTextAreaLabel = uilabel(app.Panel);
            app.ClockTextAreaLabel.Text = 'Clock';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.Editable = 'off';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Chamber'; 'Medication'; 'Dosage'};
            app.UITable.RowName = {};
            app.ResetButton = uibutton(app.MainDispenserUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.MainDispenserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_Dispenser_Final

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MainDispenserUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.MainDispenserUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainDispenserUIFigure)
        end
    end
end